classdef Appdirectkinematics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Joint1SliderLabel  matlab.ui.control.Label
        Joint1Slider       matlab.ui.control.Slider
        LoadrobotButton    matlab.ui.control.Button
        Joint2SliderLabel  matlab.ui.control.Label
        Joint2Slider       matlab.ui.control.Slider
        Joint3SliderLabel  matlab.ui.control.Label
        Joint3Slider       matlab.ui.control.Slider
        Joint5SliderLabel  matlab.ui.control.Label
        Joint5Slider       matlab.ui.control.Slider
        Joint6SliderLabel  matlab.ui.control.Label
        Joint6Slider       matlab.ui.control.Slider
        Joint4SliderLabel  matlab.ui.control.Label
        Joint4Slider       matlab.ui.control.Slider
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        theta1=0; theta2=0; theta3=0; theta4=0; theta5=0; theta6=0;
        theta = [0 0 0 0 0 0];
        data;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            axis(app.UIAxes,'off');
            app.theta1= value*pi/180;
            
            app.theta = [app.theta1 app.theta2 app.theta3 app.theta4 app.theta5 app.theta6];
            app.data.plot(app.theta)
            axis([-1500 1500 -1500 1000 -50 2400]);
            Frame = getframe(gcf);
            [image, Map] = frame2im(Frame);
            imshow(image,"parent",app.UIAxes)
    
        end

        % Button pushed function: LoadrobotButton
        function LoadrobotButtonPushed(app, event)
            app.data = evalin('base','Robot5');
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
     
            
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            axis(app.UIAxes,'off');
            app.theta2= value*pi/180;
            
            app.theta = [app.theta1 app.theta2 app.theta3 app.theta4 app.theta5 app.theta6];
            app.data.plot(app.theta)
            axis([-1500 1500 -1500 1000 -50 2400]);
            Frame = getframe(gcf);
            [image, Map] = frame2im(Frame);
            imshow(image,"parent",app.UIAxes)
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            axis(app.UIAxes,'off');
            app.theta3= value*pi/180;
            
            app.theta = [app.theta1 app.theta2 app.theta3 app.theta4 app.theta5 app.theta6];
            app.data.plot(app.theta)
            axis([-1500 1500 -1500 1000 -50 2400]);
            Frame = getframe(gcf);
            [image, Map] = frame2im(Frame);
            imshow(image,"parent",app.UIAxes)
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value;
            axis(app.UIAxes,'off');
            app.theta4= value*pi/180;
            
            app.theta = [app.theta1 app.theta2 app.theta3 app.theta4 app.theta5 app.theta6];
            app.data.plot(app.theta)
            axis([-1500 1500 -1500 1000 -50 2400]);
            Frame = getframe(gcf);
            [image, Map] = frame2im(Frame);
            imshow(image,"parent",app.UIAxes)
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = app.Joint5Slider.Value;
            axis(app.UIAxes,'off');
            app.theta5= value*pi/180;
            
            app.theta = [app.theta1 app.theta2 app.theta3 app.theta4 app.theta5 app.theta6];
            app.data.plot(app.theta)
            axis([-1500 1500 -1500 1000 -50 2400]);
            Frame = getframe(gcf);
            [image, Map] = frame2im(Frame);
            imshow(image,"parent",app.UIAxes)
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = app.Joint6Slider.Value;
            axis(app.UIAxes,'off');
            app.theta6= value*pi/180;
            
            app.theta = [app.theta1 app.theta2 app.theta3 app.theta4 app.theta5 app.theta6];
            app.data.plot(app.theta)
            axis([-1500 1500 -1500 1000 -50 2400]);
            Frame = getframe(gcf);
            [image, Map] = frame2im(Frame);
            imshow(image,"parent",app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.LoadrobotButton = uibutton(app.UIFigure, 'push');
            app.LoadrobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrobotButtonPushed, true);
            app.LoadrobotButton.Text = 'Load robot';
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-90 160];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = {'Joint3'; ''};
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-170 290];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-140 140];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-450 450];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-200 200];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appdirectkinematics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end